classdef appv4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        FilterChooseDropDown           matlab.ui.control.DropDown
        FilterChooseDropDownLabel      matlab.ui.control.Label
        LinearFiltersTab               matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        MeanFilterTab                  matlab.ui.container.Tab
        MeanEditField                  matlab.ui.control.NumericEditField
        MeanEditFieldLabel             matlab.ui.control.Label
        RMSEEditField                  matlab.ui.control.NumericEditField
        RMSEEditFieldLabel             matlab.ui.control.Label
        STDEditField                   matlab.ui.control.NumericEditField
        STDEditFieldLabel              matlab.ui.control.Label
        PSNREditField                  matlab.ui.control.NumericEditField
        PSNREditFieldLabel             matlab.ui.control.Label
        FilterImageButton              matlab.ui.control.Button
        FilterselectDropDown           matlab.ui.control.DropDown
        FilterselectDropDownLabel      matlab.ui.control.Label
        SelectImageLabel               matlab.ui.control.Label
        AddNoiseButton                 matlab.ui.control.Button
        FIlterselectionLabel           matlab.ui.control.Label
        EnterNoiseValueEditField       matlab.ui.control.EditField
        EnterNoiseValueEditFieldLabel  matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        MedianFilteringTab             matlab.ui.container.Tab
        EnterNoiseValueEditField_2     matlab.ui.control.NumericEditField
        EnterNoiseValueEditField_2Label  matlab.ui.control.Label
        MeanEditField_2                matlab.ui.control.NumericEditField
        MeanEditField_2Label           matlab.ui.control.Label
        RMSEEditField_2                matlab.ui.control.NumericEditField
        RMSEEditField_2Label           matlab.ui.control.Label
        STDEditField_2                 matlab.ui.control.NumericEditField
        STDEditField_2Label            matlab.ui.control.Label
        PSNREditField_2                matlab.ui.control.NumericEditField
        PSNREditField_2Label           matlab.ui.control.Label
        FilterImageButton_2            matlab.ui.control.Button
        SelectImageLabel_2             matlab.ui.control.Label
        AddNoiseButton_2               matlab.ui.control.Button
        MedianFilterLabel              matlab.ui.control.Label
        BrowseButton_2                 matlab.ui.control.Button
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        ImpulseNoiseFilterTab          matlab.ui.container.Tab
        RankOrderFIlterTab             matlab.ui.container.Tab
    end

    
    properties (Access = public)
        image = NaN;
        meannoise_val; 
        clean = NaN; 
        impnoise_val; 
        fselect = 0; 
    end
    
    methods (Access = public)
     function psnr = calculatePSNR(app,alt, ref)
    % Check if the input images have the same size
        if ~isequal(size(alt), size(ref))
            error('Input images must have the same dimensions');
        end

    % Compute PSNR
        max_pixel = double(max(alt,[],'all'));
        num_pixel = size(ref, 1)*size(ref, 2)*size(ref, 3);
         mse = sum((double(ref) - double(alt)).^2, 'all') / num_pixel;
        psnr = -10 * log10(mse / max_pixel^2);
    end
        function rmse = calculateRMSE(app,alt, ref)
    % Check if the input images have the same size
         if ~isequal(size(alt), size(ref))
            error('Input images must have the same dimensions');
         end
    
    % Compute the mean squared error (MSE)
         num_pixel = size(ref, 1)*size(ref, 2)*size(ref, 3);
         mse = sum((double(ref) - double(alt)).^2, 'all') / num_pixel;

    % Compute the root mean squared error (RMSE)
         rmse = sqrt(mse);
        end


        function meanVal = getImageMean(app,imageArray)
     % Ensure the image array is of type double for accurate calculations
             imageArray = double(imageArray);

     % Calculate the mean
             meanVal = mean(imageArray(:));
        end

        function stdDev = getImageStdDev(app, imageArray)
    % Ensure the image array is of type double for accurate calculations
         imageArray = double(imageArray);
    
    % Calculate the standard deviation
         stdDev = std(imageArray(:));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FilterChooseDropDown
        function FilterChooseDropDownValueChanged(app, event)
            selectedTab = app.FilterChooseDropDown.Value;
            
    
            % Switch to the corresponding tab
            switch selectedTab
                case 'Home'
                    app.TabGroup.SelectedTab = app.HomeTab;
                case 'Linear'
                    app.TabGroup.SelectedTab = app.LinearFiltersTab; % Set Tab 1 as active
                case 'Median'
                    app.TabGroup.SelectedTab = app.MedianFilteringTab; % Set Tab 2 as active
                case 'Impulse'
                    app.TabGroup.SelectedTab = app.ImpulseNoiseFilterTab; % Set Tab 3 as active
                case 'Rank order'
                    app.TabGroup.SelectedTab = app.ImpulseNoiseFilterTab;
            end 

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile({'*.tif;*.jpg;*.png;*.bmp;*.tiff', ...
        'Image Files (*.tif, *.jpg, *.png, *.bmp, *.tiff)'; ...
        '*.*', 'All Files (*.*)'}, 'Select an Image');
    
            % Check if user canceled the operation
            if isequal(file, 0)
                return; % Exit if no file is selected
            end
            
            % Construct the full file path
            fullFilePath = fullfile(path, file);
            
            % Store the full file path in an app property
            app.image = imread(fullFilePath);
            app.clean = imread(fullFilePath);
            cla(app.UIAxes,'reset');

            imshow(app.image,'Parent', app.UIAxes);
            axis(app.UIAxes, 'off');
            app.UIAxes.XColor = 'none';
            app.UIAxes.YColor = 'none';


        end

        % Callback function: AddNoiseButton, PSNREditField
        function AddNoiseButtonPushed(app, event)
    
            
       uval = app.meannoise_val; 
       uval = str2num(uval);
        %uimg = app.image;
        
    function noisy_img = addGaussianNoise(img, noise_fraction)
    % Convert image to double
    img = double(img);
    
    % Calculate image standard deviation
    img_std = std(img(:));
    disp(img_std)
    disp("noise frac: ")
    disp(noise_fraction)
    % Calculate noise standard deviation
    noise_std = noise_fraction * img_std;
    disp(noise_std)
    % Generate Gaussian noise
    noise = noise_std * randn(size(img));

    
    disp(noise_std)
    % Add noise to image
    noisy_img = img + noise;
    
    % Clip values to valid range [0, 255]
    noisy_img = max(0, min(255, noisy_img));
    noisy_img = uint8(noisy_img);
        end

        
        nois = addGaussianNoise(app.image, uval);


            cla(app.UIAxes_2,'reset');
              
            imshow(nois,'Parent', app.UIAxes_2);
            axis(app.UIAxes_2, 'off');
            app.UIAxes_2.XColor = 'none';
            app.UIAxes_2.YColor = 'none';


        rmval = app.calculateRMSE(nois,app.image);
        stval = app.getImageStdDev(nois);
        pnval = app.calculatePSNR(nois, app.image);
        mval = app.getImageMean(nois);

        app.RMSEEditField.Value = rmval;
        app.STDEditField.Value = stval;
        app.MeanEditField.Value = mval;
       app.PSNREditField.Value = pnval;

        app.image = nois;

        end

        % Value changed function: FilterselectDropDown
        function FilterselectDropDownValueChanged(app, event)
            value = app.FilterselectDropDown.Value;
            switch value
                case 'Mean Filter'
                    app.fselect = 0;
                case 'Smart Filter'
                    app.fselect = 1;

            end 
            
        end

        % Button pushed function: FilterImageButton
        function FilterImageButtonPushed(app, event)
     
     userselect = app.fselect;
     disp("usersel")
     disp(userselect)
   
     function filtered_img = meanFilter(img, window_size)
    % Validate input
    if mod(window_size, 2) == 0
        error('Window size must be an odd integer');
    end

    % Convert the input image to double precision
    img = double(img);

    % Determine if the input is grayscale or RGB
    [rows, cols, channels] = size(img);
    filtered_img = zeros(size(img));

    % Padding size
    pad_size = floor(window_size / 2);

    % Process each channel independently
    for c = 1:channels
        % Pad the current channel
        padded_channel = padarray(img(:,:,c), [pad_size, pad_size], 'symmetric');
        
        % Apply the mean filter
        for i = 1:rows
            for j = 1:cols
                % Extract the neighborhood
                neighborhood = padded_channel(i:i+window_size-1, j:j+window_size-1);
                % Compute the mean of the neighborhood
                filtered_img(i, j, c) = mean(neighborhood(:));
            end
        end
    end

    % Convert the filtered image back to the original data type
    filtered_img = cast(filtered_img, 'like', img);
    filtered_img = uint8(filtered_img);
end

function filtered_img = smartFilter(input_image, kernel)
    % Check if the kernel is 3x3
    if ~isequal(size(kernel), [3, 3])
        error('Filter kernel must be a 3x3 matrix');
    end
    
    % Check if the input is grayscale or RGB
    [rows, cols, channels] = size(input_image);
    
    % Convert the input image to double for processing
    input_image = double(input_image);
    
    % Calculate global variance across all channels
    global_var = var(input_image(:));
    
    % Preallocate the output image
    filtered_img = zeros(size(input_image));
    
    % Pad the image
    padded_image = padarray(input_image, [1 1], 'symmetric');
    
    % Apply the smart filter to each channel independently
    for c = 1:channels
        for i = 1:rows
            for j = 1:cols
                % Extract the 3x3 neighborhood
                neighborhood = padded_image(i:i+2, j:j+2, c);
                
                % Apply the kernel to the neighborhood
                local_mean = sum(neighborhood(:) .* kernel(:)) / sum(kernel(:));
                
                % Calculate local variance
                local_var = var(neighborhood(:));
                
                % Calculate the blending weight
                k = local_var / (local_var + global_var);
                
                % Apply the smart filter
                filtered_img(i, j, c) = k * input_image(i, j, c) + (1 - k) * local_mean;
            end
        end
    end
    
    % Convert back to uint8
    filtered_img = uint8(filtered_img);
end
       winsize = 3;
       fmean = NaN;
       if userselect == 0 
        
         fmean = meanFilter(app.image,winsize);
         disp("Mean")
       else
           kernel = [1,2,1;2,4,2;1,2,1;];
            kernel = 1/16 .* kernel;
         fmean = smartFilter(app.image, kernel);

       end
         rmval = app.calculateRMSE(fmean,app.clean);
        stval = app.getImageStdDev(fmean);
        pnval = app.calculatePSNR(fmean, app.clean);
        mval = app.getImageMean(fmean);

        app.RMSEEditField.Value = rmval;
        app.STDEditField.Value = stval;
        app.MeanEditField.Value = mval;
        app.PSNREditField.Value = pnval;
            
        
         cla(app.UIAxes_2,'reset');
         
         imshow(fmean,'Parent', app.UIAxes_2);
         axis(app.UIAxes_2, 'off');
         app.UIAxes_2.XColor = 'none';
         app.UIAxes_2.YColor = 'none';
        end

        % Value changed function: EnterNoiseValueEditField
        function EnterNoiseValueEditFieldValueChanged(app, event)
            value = app.EnterNoiseValueEditField.Value;
            app.meannoise_val = value;
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [file, path] = uigetfile({'*.tif;*.jpg;*.png;*.bmp;*.tiff', ...
        'Image Files (*.tif, *.jpg, *.png, *.bmp, *.tiff)'; ...
        '*.*', 'All Files (*.*)'}, 'Select an Image');
    
            % Check if user canceled the operation
            if isequal(file, 0)
                return; % Exit if no file is selected
            end
            
            % Construct the full file path
            fullFilePath = fullfile(path, file);
            
            % Store the full file path in an app property
            app.image = imread(fullFilePath);
            app.clean = imread(fullFilePath);
            cla(app.UIAxes_3,'reset');

            imshow(app.image,'Parent', app.UIAxes_3);
            axis(app.UIAxes_3, 'off');
            app.UIAxes_3.XColor = 'none';
            app.UIAxes_3.YColor = 'none';
        end

        % Value changed function: EnterNoiseValueEditField_2
        function EnterNoiseValueEditField_2ValueChanged(app, event)
            value = app.EnterNoiseValueEditField_2.Value;
            app.impnoise_val = value;
        end

        % Button pushed function: AddNoiseButton_2
        function AddNoiseButton_2Pushed(app, event)
      function corrupted_image = addImpulseNoise(image, corruption_rate)
            % Get the dimensions of the image
            [rows, cols, channels] = size(image);
        
            % Create a copy of the image to corrupt
            corrupted_image = image;
        
            % Total number of pixels
            total_pixels = rows * cols;
        
            % Number of pixels to corrupt
            num_corrupted_pixels = round(corruption_rate * total_pixels);
        
            % Randomly select pixel indices to corrupt
            pixel_indices = randperm(total_pixels, num_corrupted_pixels);
        
            % Convert linear indices to row, col format
            [row_indices, col_indices] = ind2sub([rows, cols], pixel_indices);
        
            % Apply the noise
            for i = 1:num_corrupted_pixels
                r = row_indices(i);
                c = col_indices(i);
        
                % Random impulse noise: Assign random values in the range [0, 255]
                corrupted_pixel_value = randi([0, 255], 1, 1, channels);
                corrupted_image(r, c, :) = corrupted_pixel_value;
                end
   end

    nois = addImpulseNoise(app.image, app.impnoise_val);


            cla(app.UIAxes_4,'reset');
              
            imshow(nois,'Parent', app.UIAxes_4);
            axis(app.UIAxes_4, 'off');
            app.UIAxes_4.XColor = 'none';
            app.UIAxes_4.YColor = 'none';


        rmval = app.calculateRMSE(nois,app.image);
        stval = app.getImageStdDev(nois);
        pnval = app.calculatePSNR(nois, app.image);
        mval = app.getImageMean(nois);

        app.RMSEEditField_2.Value = rmval;
        app.STDEditField_2.Value = stval;
        app.MeanEditField_2.Value = mval;
       app.PSNREditField_2.Value = pnval;

        app.image = nois;


        end

        % Button pushed function: FilterImageButton_2
        function FilterImageButton_2Pushed(app, event)
          function filtered_image = medianFilter(input_image, window_size)
            % Convert input image to double precision
            input_image = double(input_image);
            
            % Check if the input is grayscale or RGB
            [rows, cols, channels] = size(input_image);
            
            % Input validation for window size
            if mod(window_size, 2) == 0
                error('Window size must be odd');
            end
            
            % Padding size
            padding = (window_size - 1) / 2;
            
            % Preallocate the output image
            filtered_image = zeros(size(input_image));
            
            % Apply the median filter to each channel independently
            for c = 1:channels
                % Pad the current channel
                padded_channel = padarray(input_image(:,:,c), [padding padding], 'symmetric');
                
                % Filter the channel
                for i = 1:rows
                    for j = 1:cols
                        window = padded_channel(i:i+window_size-1, j:j+window_size-1);
                        filtered_image(i, j, c) = median(window(:));
                    end
                end
            end
            
            % Convert the filtered image back to the original data type
            filtered_image = cast(filtered_image, 'like', input_image);
            filtered_image = uint8(filtered_image);
          end

            nois = medianFilter(app.image,3);
          cla(app.UIAxes_4,'reset');
              
            imshow(nois,'Parent', app.UIAxes_4);
            axis(app.UIAxes_4, 'off');
            app.UIAxes_4.XColor = 'none';
            app.UIAxes_4.YColor = 'none';


        rmval = app.calculateRMSE(nois,app.clean);
        stval = app.getImageStdDev(nois);
        pnval = app.calculatePSNR(nois, app.clean);
        mval = app.getImageMean(nois);

        app.RMSEEditField_2.Value = rmval;
        app.STDEditField_2.Value = stval;
        app.MeanEditField_2.Value = mval;
        app.PSNREditField_2.Value = pnval;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.FilterChooseDropDownLabel = uilabel(app.HomeTab);
            app.FilterChooseDropDownLabel.Text = 'Filter Choose';
            app.FilterChooseDropDown = uidropdown(app.HomeTab);
            app.FilterChooseDropDown.Items = {'Home', 'Linear', 'Median', 'Impulse', 'Option 4'};
            app.FilterChooseDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterChooseDropDownValueChanged, true);
            app.FilterChooseDropDown.Value = 'Linear';
            app.LinearFiltersTab = uitab(app.TabGroup);
            app.LinearFiltersTab.Title = 'Linear Filters';
            app.TabGroup2 = uitabgroup(app.LinearFiltersTab);
            app.MeanFilterTab = uitab(app.TabGroup2);
            app.MeanFilterTab.Title = 'Mean Filter';
            app.UIAxes = uiaxes(app.MeanFilterTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.MeanFilterTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.BrowseButton = uibutton(app.MeanFilterTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.EnterNoiseValueEditFieldLabel = uilabel(app.MeanFilterTab);
            app.EnterNoiseValueEditFieldLabel.Text = 'Enter Noise Value ';
            app.EnterNoiseValueEditField = uieditfield(app.MeanFilterTab, 'text');
            app.EnterNoiseValueEditField.ValueChangedFcn = createCallbackFcn(app, @EnterNoiseValueEditFieldValueChanged, true);
            app.FIlterselectionLabel = uilabel(app.MeanFilterTab);
            app.FIlterselectionLabel.Text = 'FIlter selection';
            app.AddNoiseButton = uibutton(app.MeanFilterTab, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = 'Add Noise';
            app.SelectImageLabel = uilabel(app.MeanFilterTab);
            app.SelectImageLabel.Text = 'Select Image';
            app.FilterselectDropDownLabel = uilabel(app.MeanFilterTab);
            app.FilterselectDropDownLabel.Text = 'Filter select';
            app.FilterselectDropDown = uidropdown(app.MeanFilterTab);
            app.FilterselectDropDown.Items = {'Mean Filter', 'Smart Filter'};
            app.FilterselectDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterselectDropDownValueChanged, true);
            app.FilterselectDropDown.Value = 'Mean Filter';
            app.FilterImageButton = uibutton(app.MeanFilterTab, 'push');
            app.FilterImageButton.ButtonPushedFcn = createCallbackFcn(app, @FilterImageButtonPushed, true);
            app.FilterImageButton.Text = 'Filter Image';
            app.PSNREditFieldLabel = uilabel(app.MeanFilterTab);
            app.PSNREditFieldLabel.Text = 'PSNR';
            app.PSNREditField = uieditfield(app.MeanFilterTab, 'numeric');
            app.PSNREditField.ValueChangedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.STDEditFieldLabel = uilabel(app.MeanFilterTab);
            app.STDEditFieldLabel.Text = 'STD';
            app.STDEditField = uieditfield(app.MeanFilterTab, 'numeric');
            app.RMSEEditFieldLabel = uilabel(app.MeanFilterTab);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSEEditField = uieditfield(app.MeanFilterTab, 'numeric');
            app.MeanEditFieldLabel = uilabel(app.MeanFilterTab);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.MeanFilterTab, 'numeric');
            app.MedianFilteringTab = uitab(app.TabGroup);
            app.MedianFilteringTab.Title = 'Median Filtering';
            app.UIAxes_3 = uiaxes(app.MedianFilteringTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.UIAxes_4 = uiaxes(app.MedianFilteringTab);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.ZTick = [];
            app.BrowseButton_2 = uibutton(app.MedianFilteringTab, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.MedianFilterLabel = uilabel(app.MedianFilteringTab);
            app.MedianFilterLabel.Text = 'Median Filter';
            app.AddNoiseButton_2 = uibutton(app.MedianFilteringTab, 'push');
            app.AddNoiseButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButton_2Pushed, true);
            app.AddNoiseButton_2.Text = 'Add Noise';
            app.SelectImageLabel_2 = uilabel(app.MedianFilteringTab);
            app.SelectImageLabel_2.Text = 'Select Image';
            app.FilterImageButton_2 = uibutton(app.MedianFilteringTab, 'push');
            app.FilterImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @FilterImageButton_2Pushed, true);
            app.FilterImageButton_2.Text = 'Filter Image';
            app.PSNREditField_2Label = uilabel(app.MedianFilteringTab);
            app.PSNREditField_2Label.Text = 'PSNR';
            app.PSNREditField_2 = uieditfield(app.MedianFilteringTab, 'numeric');
            app.STDEditField_2Label = uilabel(app.MedianFilteringTab);
            app.STDEditField_2Label.Text = 'STD';
            app.STDEditField_2 = uieditfield(app.MedianFilteringTab, 'numeric');
            app.RMSEEditField_2Label = uilabel(app.MedianFilteringTab);
            app.RMSEEditField_2Label.Text = 'RMSE';
            app.RMSEEditField_2 = uieditfield(app.MedianFilteringTab, 'numeric');
            app.MeanEditField_2Label = uilabel(app.MedianFilteringTab);
            app.MeanEditField_2Label.Text = 'Mean';
            app.MeanEditField_2 = uieditfield(app.MedianFilteringTab, 'numeric');
            app.EnterNoiseValueEditField_2Label = uilabel(app.MedianFilteringTab);
            app.EnterNoiseValueEditField_2Label.Text = 'Enter Noise Value';
            app.EnterNoiseValueEditField_2 = uieditfield(app.MedianFilteringTab, 'numeric');
            app.EnterNoiseValueEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterNoiseValueEditField_2ValueChanged, true);
            app.ImpulseNoiseFilterTab = uitab(app.TabGroup);
            app.ImpulseNoiseFilterTab.Title = 'Impulse Noise Filter';
            app.RankOrderFIlterTab = uitab(app.TabGroup);
            app.RankOrderFIlterTab.Title = 'Rank Order FIlter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appv4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end