classdef appv5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        FilterChooseDropDown            matlab.ui.control.DropDown
        FilterChooseDropDownLabel       matlab.ui.control.Label
        NonLinearTab                    matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        NonLinearTab_2                  matlab.ui.container.Tab
        AddNoiseButton                  matlab.ui.control.Button
        ImpulseDensitySlider            matlab.ui.control.Slider
        ImpulseNoiseDensitySliderLabel  matlab.ui.control.Label
        GaussianSTDSlider               matlab.ui.control.Slider
        GaussianSTDLabel                matlab.ui.control.Label
        GaussianMeanSlider              matlab.ui.control.Slider
        GaussianMeanSliderLabel         matlab.ui.control.Label
        MeanEditField                   matlab.ui.control.NumericEditField
        MeanEditFieldLabel              matlab.ui.control.Label
        RMSEEditField                   matlab.ui.control.NumericEditField
        RMSEEditFieldLabel              matlab.ui.control.Label
        STDEditField                    matlab.ui.control.NumericEditField
        STDEditFieldLabel               matlab.ui.control.Label
        PSNREditField                   matlab.ui.control.NumericEditField
        PSNREditFieldLabel              matlab.ui.control.Label
        FilterImageButton               matlab.ui.control.Button
        FilterselectDropDown            matlab.ui.control.DropDown
        FilterselectDropDownLabel       matlab.ui.control.Label
        SelectImageLabel                matlab.ui.control.Label
        FIlterselectionLabel            matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        LinearFiltersTab                matlab.ui.container.Tab
        TabGroup2_2                     matlab.ui.container.TabGroup
        MeanFilterTab_2                 matlab.ui.container.Tab
        AddNoiseButton_2                matlab.ui.control.Button
        ImpulseDensitySlider_2          matlab.ui.control.Slider
        ImpulseNoiseDensitySliderLabel_2  matlab.ui.control.Label
        GaussianSTDSlider_2             matlab.ui.control.Slider
        GaussianSTDSlider_2Label        matlab.ui.control.Label
        GaussianMeanSlider_2            matlab.ui.control.Slider
        GaussianMeanSlider_2Label       matlab.ui.control.Label
        MeanEditField_2                 matlab.ui.control.NumericEditField
        MeanEditField_2Label            matlab.ui.control.Label
        RMSEEditField_2                 matlab.ui.control.NumericEditField
        RMSEEditField_2Label            matlab.ui.control.Label
        STDEditField_2                  matlab.ui.control.NumericEditField
        STDEditField_2Label             matlab.ui.control.Label
        PSNREditField_2                 matlab.ui.control.NumericEditField
        PSNREditField_2Label            matlab.ui.control.Label
        FilterImageButton_2             matlab.ui.control.Button
        FilterselectDropDown_2          matlab.ui.control.DropDown
        FilterselectDropDown_2Label     matlab.ui.control.Label
        SelectImageLabel_2              matlab.ui.control.Label
        FIlterselectionLabel_2          matlab.ui.control.Label
        BrowseButton_2                  matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        image = NaN;
        meannoise_val; 
        clean = NaN; 
        image2 = NaN;        % For the second tab
        clean2 = NaN;        % Original clean image for the second tab
        impnoise_val; 
        fselect = 0; 
        gaussian_mean_val = 0; % Default value for Gaussian mean
        gaussian_std_val = 10; % Default value for Gaussian std deviation
        impulse_density_val = 0.05; % Default value for Impulse noise density
    end
    
    methods (Access = public)
        function psnr = calculatePSNR(app, alt, ref)
            % Check if the input images have the same size
            if ~isequal(size(alt), size(ref))
                error('Input images must have the same dimensions');
            end

            % Compute PSNR
            max_pixel = double(max(alt, [], 'all'));
            num_pixel = size(ref, 1) * size(ref, 2) * size(ref, 3);
            mse = sum((double(ref) - double(alt)).^2, 'all') / num_pixel;
            psnr = -10 * log10(mse / max_pixel^2);
        end

        function rmse = calculateRMSE(app, alt, ref)
            % Check if the input images have the same size
            if ~isequal(size(alt), size(ref))
                error('Input images must have the same dimensions');
            end

            % Compute the mean squared error (MSE)
            num_pixel = size(ref, 1) * size(ref, 2) * size(ref, 3);
            mse = sum((double(ref) - double(alt)).^2, 'all') / num_pixel;

            % Compute the root mean squared error (RMSE)
            rmse = sqrt(mse);
        end

        function meanVal = getImageMean(app, imageArray)
            % Ensure the image array is of type double for accurate calculations
            imageArray = double(imageArray);

            % Calculate the mean
            meanVal = mean(imageArray(:));
        end

        function stdDev = getImageStdDev(app, imageArray)
            % Ensure the image array is of type double for accurate calculations
            imageArray = double(imageArray);

            % Calculate the standard deviation
            stdDev = std(imageArray(:));
        end
        
        function FilterChooseDropDownValueChanged(app, event)
            selectedTab = app.FilterChooseDropDown.Value;

            % Switch to the corresponding tab
            switch selectedTab
                case 'Home'
                    app.TabGroup.SelectedTab = app.HomeTab;
                case 'Linear'
                    app.TabGroup.SelectedTab = app.NonLinearTab;
                case 'Median'
                    app.TabGroup.SelectedTab = app.MedianFilteringTab;
                case 'Impulse'
                    app.TabGroup.SelectedTab = app.ImpulseNoiseFilterTab;
                case 'Rank order'
                    app.TabGroup.SelectedTab = app.ImpulseNoiseFilterTab;
            end
        end
    
        % Add Gaussian Noise
        function noisy_img = addGaussianNoise(app, img)
            % Convert image to double
            img = double(img);
    
            % Get Gaussian noise parameters
            noise_mean = app.gaussian_mean_val; % Mean value from slider
            noise_std = app.gaussian_std_val;   % Std deviation from slider
    
            % Generate Gaussian noise
            noise = noise_std * randn(size(img)) + noise_mean;
    
            % Add noise to the image
            noisy_img = img + noise;
    
            % Clip values to the range [0, 255]
            noisy_img = max(0, min(255, noisy_img));
            noisy_img = uint8(noisy_img); % Convert back to uint8
        end
        
        % Add Impulse Noise
        function noisy_img = addImpulseNoise(app, img)
            % Get Impulse noise density
            density = app.impulse_density_val; % Density value from slider
    
            % Total number of pixels
            [rows, cols, channels] = size(img);
            total_pixels = rows * cols;
    
            % Calculate the number of corrupted pixels
            num_corrupted_pixels = round(density * total_pixels);
    
            % Create a copy of the original image
            noisy_img = img;
    
            % Randomly select pixels to corrupt
            pixel_indices = randperm(total_pixels, num_corrupted_pixels);
    
            % Corrupt selected pixels with random salt-and-pepper noise
            for i = 1:num_corrupted_pixels
                [r, c] = ind2sub([rows, cols], pixel_indices(i));
                % Randomly assign either 0 (black) or 255 (white)
                noisy_img(r, c, :) = randi([0, 255], 1, 1, channels);
            end
        end
        % Mean Filter Implementation
        function filtered_img = meanFilter(app, img, window_size)
            % Validate input
            if mod(window_size, 2) == 0
                error('Window size must be an odd integer');
            end
    
            % Convert the input image to double precision
            img = double(img);
    
            % Determine if the input is grayscale or RGB
            [rows, cols, channels] = size(img);
            filtered_img = zeros(size(img));
    
            % Padding size
            pad_size = floor(window_size / 2);
    
            % Process each channel independently
            for c = 1:channels
                % Pad the current channel
                padded_channel = padarray(img(:,:,c), [pad_size, pad_size], 'symmetric');
    
                % Apply the mean filter
                for i = 1:rows
                    for j = 1:cols
                        % Extract the neighborhood
                        neighborhood = padded_channel(i:i+window_size-1, j:j+window_size-1);
                        % Compute the mean of the neighborhood
                        filtered_img(i, j, c) = mean(neighborhood(:));
                    end
                end
            end
    
            % Convert the filtered image back to uint8
            filtered_img = uint8(filtered_img);
        end

        % Smart Filter Implementation
        function filtered_img = smartFilter(app, img, kernel)
            % Check if the kernel is 3x3
            if ~isequal(size(kernel), [3, 3])
                error('Filter kernel must be a 3x3 matrix');
            end
    
            % Check if the input is grayscale or RGB
            [rows, cols, channels] = size(img);
    
            % Convert the input image to double for processing
            img = double(img);
    
            % Calculate global variance across all channels
            global_var = var(img(:));
    
            % Preallocate the output image
            filtered_img = zeros(size(img));
    
            % Pad the image
            padded_image = padarray(img, [1 1], 'symmetric');
    
            % Apply the smart filter to each channel independently
            for c = 1:channels
                for i = 1:rows
                    for j = 1:cols
                        % Extract the 3x3 neighborhood
                        neighborhood = padded_image(i:i+2, j:j+2, c);
    
                        % Apply the kernel to the neighborhood
                        local_mean = sum(neighborhood(:) .* kernel(:)) / sum(kernel(:));
    
                        % Calculate local variance
                        local_var = var(neighborhood(:));
    
                        % Calculate the blending weight
                        k = local_var / (local_var + global_var);
    
                        % Apply the smart filter
                        filtered_img(i, j, c) = k * img(i, j, c) + (1 - k) * local_mean;
                    end
                end
            end

                % Convert back to uint8
               filtered_img = uint8(filtered_img);
    end    

    % Median Filter Implementation
    function filtered_img = medianFilter(app, img, window_size)
        % Convert input image to double precision
        img = double(img);

        % Check if the input is grayscale or RGB
        [rows, cols, channels] = size(img);

        % Input validation for window size
        if mod(window_size, 2) == 0
            error('Window size must be odd');
        end

        % Padding size
        padding = (window_size - 1) / 2;

        % Preallocate the output image
        filtered_img = zeros(size(img));

        % Apply the median filter to each channel independently
        for c = 1:channels
            % Pad the current channel
            padded_channel = padarray(img(:,:,c), [padding padding], 'symmetric');

            % Filter the channel
            for i = 1:rows
                for j = 1:cols
                    window = padded_channel(i:i+window_size-1, j:j+window_size-1);
                    filtered_img(i, j, c) = median(window(:));
                end
            end
        end
    end
        end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile({'*.tif;*.jpg;*.png;*.bmp;*.tiff', ...
        'Image Files (*.tif, *.jpg, *.png, *.bmp, *.tiff)'; ...
        '*.*', 'All Files (*.*)'}, 'Select an Image');
    
            % Check if user canceled the operation
            if isequal(file, 0)
                return; % Exit if no file is selected
            end
            
            % Construct the full file path
            fullFilePath = fullfile(path, file);
            
            % Store the full file path in an app property
            app.image = imread(fullFilePath);
            app.clean = imread(fullFilePath);
            cla(app.UIAxes,'reset');

            imshow(app.image,'Parent', app.UIAxes);
            axis(app.UIAxes, 'off');
            app.UIAxes.XColor = 'none';
            app.UIAxes.YColor = 'none';
        end

        % Callback function: AddNoiseButton, PSNREditField
        function AddNoiseButtonPushed(app, event)
            % Debugging statement
            disp("AddNoiseButtonPushed called");
        
            % Initialize the noisy image as the original image
            noisy_img = app.image;
        
            % Check and apply Gaussian noise if its values are non-default
            if app.gaussian_std_val > 0 || app.gaussian_mean_val ~= 0
                disp("Adding Gaussian Noise");
                noisy_img = app.addGaussianNoise(noisy_img); % Apply Gaussian noise
            else
                disp("Gaussian Noise not added (Mean = 0, Std Dev = 0)");
            end
        
            % Check and apply Impulse noise if its density is non-default
            if app.impulse_density_val > 0
                disp("Adding Impulse Noise");
                noisy_img = app.addImpulseNoise(noisy_img); % Apply Impulse noise
            else
                disp("Impulse Noise not added (Density = 0)");
            end
        
            % Display the noisy image in app.UIAxes_2
            cla(app.UIAxes_2, 'reset'); % Clear the existing axes
            imshow(noisy_img, 'Parent', app.UIAxes_2); % Show the noisy image
            axis(app.UIAxes_2, 'off'); % Turn off axes ticks
        
            % Update metrics for noisy image
            rmval = app.calculateRMSE(noisy_img, app.clean); % RMSE
            stval = app.getImageStdDev(noisy_img);          % Standard deviation
            pnval = app.calculatePSNR(noisy_img, app.clean); % PSNR
            mval = app.getImageMean(noisy_img);             % Mean value
        
            % Set metric values in the app
            app.RMSEEditField.Value = rmval; % Update RMSE field
            app.STDEditField.Value = stval; % Update STD field
            app.MeanEditField.Value = mval; % Update Mean field
            app.PSNREditField.Value = pnval; % Update PSNR field
        
            % Save the noisy image to app property
            app.image = noisy_img; % Store the noisy image for filtering
        end

        % Value changed function: FilterselectDropDown
        function FilterselectDropDownValueChanged(app, event)
            value = app.FilterselectDropDown.Value;
            switch value
                case 'Mean Filter'
                    app.fselect = 0;
                case 'Smart Filter'
                    app.fselect = 1;

            end 
            
        end

        % Button pushed function: FilterImageButton
        function FilterImageButtonPushed(app, event)
                    % Debugging statement
            disp("FilterImageButtonPushed called");
        
            % Check if there is a noisy image available
            if isequal(app.image, app.clean)
                disp("No noise added. Using loaded image for filtering.");
                img_to_filter = app.clean; % Use the loaded image
            else
                disp("Noisy image detected. Filtering the noisy image.");
                img_to_filter = app.image; % Use the noisy image
            end
        
            % Get user selection for filter type (0 = Mean Filter, 1 = Smart Filter)
            userselect = app.fselect; % Assuming app.fselect is set by a dropdown or toggle
            disp("User Selection:");
            disp(userselect);
        
            % Initialize filtered image
            filtered_img = NaN;
        
            % Define a 3x3 window size for the mean filter
            winsize = 3;
        
            % Filter selection and application
            if userselect == 0 % Mean Filter selected
                % Apply mean filter
                filtered_img = app.meanFilter(img_to_filter, winsize); % Call as app method
                disp("Applied Mean Filter");
            else % Smart Filter selected
                % Define a kernel for smart filter
                kernel = [1, 2, 1; 2, 4, 2; 1, 2, 1];
                kernel = kernel / sum(kernel(:)); % Normalize kernel
                % Apply smart filter
                filtered_img = app.smartFilter(img_to_filter, kernel); % Call as app method
                disp("Applied Smart Filter");
            end
        
            % Update image quality metrics
            rmval = app.calculateRMSE(filtered_img, app.clean); % RMSE
            stval = app.getImageStdDev(filtered_img);          % Standard deviation
            pnval = app.calculatePSNR(filtered_img, app.clean); % PSNR
            mval = app.getImageMean(filtered_img);             % Mean value
        
            % Update metric fields in the app
            app.RMSEEditField.Value = rmval; % Update RMSE
            app.STDEditField.Value = stval; % Update STD
            app.MeanEditField.Value = mval; % Update Mean
            app.PSNREditField.Value = pnval; % Update PSNR
        
            % Display the filtered image in app.UIAxes_2
            cla(app.UIAxes_2, 'reset'); % Clear previous image
            imshow(filtered_img, 'Parent', app.UIAxes_2); % Show filtered image
            axis(app.UIAxes_2, 'off'); % Turn off axes ticks
            app.UIAxes_2.XColor = 'none'; % Hide X-axis
            app.UIAxes_2.YColor = 'none'; % Hide Y-axis
        
            % Save the filtered image to app.image for further processing
            app.image = filtered_img;
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [file, path] = uigetfile({'*.tif;*.jpg;*.png;*.bmp;*.tiff', ...
        'Image Files (*.tif, *.jpg, *.png, *.bmp, *.tiff)'; ...
        '*.*', 'All Files (*.*)'}, 'Select an Image');
    
            % Check if user canceled the operation
            if isequal(file, 0)
                return; % Exit if no file is selected
            end
            
            % Construct the full file path
            fullFilePath = fullfile(path, file);
            
            % Store the full file path in an app property
            app.image = imread(fullFilePath);
            app.clean = imread(fullFilePath);
            cla(app.UIAxes_3,'reset');

            imshow(app.image,'Parent', app.UIAxes_3);
            axis(app.UIAxes_3, 'off');
            app.UIAxes_3.XColor = 'none';
            app.UIAxes_3.YColor = 'none';
        end

        % Callback function: not associated with a component
        function FilterImageButton_2Pushed(app, event)
          nois = app.medianFilter(app.image, 3);
        end

        % Value changed function: GaussianMeanSlider
        function GaussianMeanSliderValueChanged(app, event)
            % Update Gaussian Mean value from slider
            app.gaussian_mean_val = event.Value;
        end

        % Callback function: GaussianSTDSlider, GaussianSTDSlider
        function GaussianSTDSliderValueChanged(app, event)
            % Update Gaussian Std Dev value from slider
            app.gaussian_std_val = event.Value;            
        end

        % Callback function: ImpulseDensitySlider, ImpulseDensitySlider
        function ImpulseDensitySliderValueChanged(app, event)
            % Update Impulse Noise Density from slider
            app.impulse_density_val = event.Value;  


        end

        % Value changing function: GaussianMeanSlider
        function GaussianMeanSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: AddNoiseButton_2
        function AddNoiseButton_2Pushed(app, event)
            
        end

        % Callback function: GaussianMeanSlider_2, GaussianMeanSlider_2
        function GaussianMeanSlider_2ValueChanged(app, event)
            changingValue = event.Value;            
        end

        % Value changed function: GaussianSTDSlider_2
        function GaussianSTDSlider_2ValueChanged(app, event)
            changingValue = event.Value;
        end

        % Value changed function: ImpulseDensitySlider_2
        function ImpulseDensitySlider_2ValueChanged(app, event)
            changingValue = event.Value;           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.FilterChooseDropDownLabel = uilabel(app.HomeTab);
            app.FilterChooseDropDownLabel.Text = 'Filter Choose';
            app.FilterChooseDropDown = uidropdown(app.HomeTab);
            app.FilterChooseDropDown.Items = {'Home', 'Linear', 'Median', 'Impulse', 'Option 4'};
            app.FilterChooseDropDown.Value = 'Linear';
            app.NonLinearTab = uitab(app.TabGroup);
            app.NonLinearTab.Title = 'Non-Linear';
            app.TabGroup2 = uitabgroup(app.NonLinearTab);
            app.NonLinearTab_2 = uitab(app.TabGroup2);
            app.NonLinearTab_2.Title = 'Non-Linear';
            app.UIAxes = uiaxes(app.NonLinearTab_2);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.NonLinearTab_2);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.BrowseButton = uibutton(app.NonLinearTab_2, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FIlterselectionLabel = uilabel(app.NonLinearTab_2);
            app.FIlterselectionLabel.Text = 'FIlter selection';
            app.SelectImageLabel = uilabel(app.NonLinearTab_2);
            app.SelectImageLabel.Text = 'Select Image';
            app.FilterselectDropDownLabel = uilabel(app.NonLinearTab_2);
            app.FilterselectDropDownLabel.Text = 'Filter select';
            app.FilterselectDropDown = uidropdown(app.NonLinearTab_2);
            app.FilterselectDropDown.Items = {'Median Filter', 'Smart Filter', 'Impulse Noise w/ DRID', 'Rank Order ER (median)'};
            app.FilterselectDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterselectDropDownValueChanged, true);
            app.FilterselectDropDown.Placeholder = 'choose';
            app.FilterselectDropDown.Value = 'Median Filter';
            app.FilterImageButton = uibutton(app.NonLinearTab_2, 'push');
            app.FilterImageButton.ButtonPushedFcn = createCallbackFcn(app, @FilterImageButtonPushed, true);
            app.FilterImageButton.Text = 'Filter Image';
            app.PSNREditFieldLabel = uilabel(app.NonLinearTab_2);
            app.PSNREditFieldLabel.Text = 'PSNR';
            app.PSNREditField = uieditfield(app.NonLinearTab_2, 'numeric');
            app.PSNREditField.ValueChangedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.STDEditFieldLabel = uilabel(app.NonLinearTab_2);
            app.STDEditFieldLabel.Text = 'STD';
            app.STDEditField = uieditfield(app.NonLinearTab_2, 'numeric');
            app.RMSEEditFieldLabel = uilabel(app.NonLinearTab_2);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSEEditField = uieditfield(app.NonLinearTab_2, 'numeric');
            app.MeanEditFieldLabel = uilabel(app.NonLinearTab_2);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.NonLinearTab_2, 'numeric');
            app.GaussianMeanSliderLabel = uilabel(app.NonLinearTab_2);
            app.GaussianMeanSliderLabel.Text = 'Gaussian Mean';
            app.GaussianMeanSlider = uislider(app.NonLinearTab_2);
            app.GaussianMeanSlider.Limits = [-50 50];
            app.GaussianMeanSlider.MajorTicks = [-50 -30 -10 0 10 30 50];
            app.GaussianMeanSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianMeanSliderValueChanged, true);
            app.GaussianMeanSlider.ValueChangingFcn = createCallbackFcn(app, @GaussianMeanSliderValueChanging, true);
            app.GaussianSTDLabel = uilabel(app.NonLinearTab_2);
            app.GaussianSTDLabel.Text = 'Gaussian STD';
            app.GaussianSTDSlider = uislider(app.NonLinearTab_2);
            app.GaussianSTDSlider.Limits = [0 50];
            app.GaussianSTDSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianSTDSliderValueChanged, true);
            app.GaussianSTDSlider.ValueChangingFcn = createCallbackFcn(app, @GaussianSTDSliderValueChanged, true);
            app.ImpulseNoiseDensitySliderLabel = uilabel(app.NonLinearTab_2);
            app.ImpulseNoiseDensitySliderLabel.Text = 'Impulse Noise Density';
            app.ImpulseDensitySlider = uislider(app.NonLinearTab_2);
            app.ImpulseDensitySlider.Limits = [0 1];
            app.ImpulseDensitySlider.MajorTicks = [0 0.05 0.2 0.4 0.6 0.8 1];
            app.ImpulseDensitySlider.ValueChangedFcn = createCallbackFcn(app, @ImpulseDensitySliderValueChanged, true);
            app.ImpulseDensitySlider.ValueChangingFcn = createCallbackFcn(app, @ImpulseDensitySliderValueChanged, true);
            app.AddNoiseButton = uibutton(app.NonLinearTab_2, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = 'AddNoise';
            app.LinearFiltersTab = uitab(app.TabGroup);
            app.LinearFiltersTab.Title = 'Linear Filters';
            app.TabGroup2_2 = uitabgroup(app.LinearFiltersTab);
            app.MeanFilterTab_2 = uitab(app.TabGroup2_2);
            app.MeanFilterTab_2.Title = 'Mean Filter';
            app.UIAxes_3 = uiaxes(app.MeanFilterTab_2);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.UIAxes_4 = uiaxes(app.MeanFilterTab_2);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.ZTick = [];
            app.BrowseButton_2 = uibutton(app.MeanFilterTab_2, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.FIlterselectionLabel_2 = uilabel(app.MeanFilterTab_2);
            app.FIlterselectionLabel_2.Text = 'FIlter selection';
            app.SelectImageLabel_2 = uilabel(app.MeanFilterTab_2);
            app.SelectImageLabel_2.Text = 'Select Image';
            app.FilterselectDropDown_2Label = uilabel(app.MeanFilterTab_2);
            app.FilterselectDropDown_2Label.Text = 'Filter select';
            app.FilterselectDropDown_2 = uidropdown(app.MeanFilterTab_2);
            app.FilterselectDropDown_2.Items = {'Linear Convolution', 'Mean Filter', 'Rank-Order EV', 'Unsharp Masking', 'Laplace I', 'Laplace II'};
            app.FilterselectDropDown_2.Value = 'Linear Convolution';
            app.FilterImageButton_2 = uibutton(app.MeanFilterTab_2, 'push');
            app.FilterImageButton_2.Text = 'Filter Image';
            app.PSNREditField_2Label = uilabel(app.MeanFilterTab_2);
            app.PSNREditField_2Label.Text = 'PSNR';
            app.PSNREditField_2 = uieditfield(app.MeanFilterTab_2, 'numeric');
            app.STDEditField_2Label = uilabel(app.MeanFilterTab_2);
            app.STDEditField_2Label.Text = 'STD';
            app.STDEditField_2 = uieditfield(app.MeanFilterTab_2, 'numeric');
            app.RMSEEditField_2Label = uilabel(app.MeanFilterTab_2);
            app.RMSEEditField_2Label.Text = 'RMSE';
            app.RMSEEditField_2 = uieditfield(app.MeanFilterTab_2, 'numeric');
            app.MeanEditField_2Label = uilabel(app.MeanFilterTab_2);
            app.MeanEditField_2Label.Text = 'Mean';
            app.MeanEditField_2 = uieditfield(app.MeanFilterTab_2, 'numeric');
            app.GaussianMeanSlider_2Label = uilabel(app.MeanFilterTab_2);
            app.GaussianMeanSlider_2Label.Text = 'Gaussian Mean';
            app.GaussianMeanSlider_2 = uislider(app.MeanFilterTab_2);
            app.GaussianMeanSlider_2.Limits = [-50 50];
            app.GaussianMeanSlider_2.MajorTicks = [-50 -30 -10 0 10 30 50];
            app.GaussianMeanSlider_2.ValueChangedFcn = createCallbackFcn(app, @GaussianMeanSlider_2ValueChanged, true);
            app.GaussianMeanSlider_2.ValueChangingFcn = createCallbackFcn(app, @GaussianMeanSlider_2ValueChanged, true);
            app.GaussianSTDSlider_2Label = uilabel(app.MeanFilterTab_2);
            app.GaussianSTDSlider_2Label.Text = 'Gaussian STD';
            app.GaussianSTDSlider_2 = uislider(app.MeanFilterTab_2);
            app.GaussianSTDSlider_2.Limits = [0 50];
            app.GaussianSTDSlider_2.ValueChangedFcn = createCallbackFcn(app, @GaussianSTDSlider_2ValueChanged, true);
            app.ImpulseNoiseDensitySliderLabel_2 = uilabel(app.MeanFilterTab_2);
            app.ImpulseNoiseDensitySliderLabel_2.Text = 'Impulse Noise Density';
            app.ImpulseDensitySlider_2 = uislider(app.MeanFilterTab_2);
            app.ImpulseDensitySlider_2.Limits = [0 1];
            app.ImpulseDensitySlider_2.MajorTicks = [0 0.05 0.2 0.4 0.6 0.8 1];
            app.ImpulseDensitySlider_2.ValueChangedFcn = createCallbackFcn(app, @ImpulseDensitySlider_2ValueChanged, true);
            app.AddNoiseButton_2 = uibutton(app.MeanFilterTab_2, 'push');
            app.AddNoiseButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButton_2Pushed, true);
            app.AddNoiseButton_2.Text = 'AddNoise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appv5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end