classdef appv5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        FilterChooseDropDown            matlab.ui.control.DropDown
        FilterChooseDropDownLabel       matlab.ui.control.Label
        NonLinearTab                    matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        NonLinearTab_2                  matlab.ui.container.Tab
        AddNoiseButton                  matlab.ui.control.Button
        ImpulseDensitySlider            matlab.ui.control.Slider
        ImpulseNoiseDensitySliderLabel  matlab.ui.control.Label
        GaussianSTDSlider               matlab.ui.control.Slider
        GaussianSTDLabel                matlab.ui.control.Label
        GaussianMeanSlider              matlab.ui.control.Slider
        GaussianMeanSliderLabel         matlab.ui.control.Label
        MeanEditField                   matlab.ui.control.NumericEditField
        MeanEditFieldLabel              matlab.ui.control.Label
        RMSEEditField                   matlab.ui.control.NumericEditField
        RMSEEditFieldLabel              matlab.ui.control.Label
        STDEditField                    matlab.ui.control.NumericEditField
        STDEditFieldLabel               matlab.ui.control.Label
        PSNREditField                   matlab.ui.control.NumericEditField
        PSNREditFieldLabel              matlab.ui.control.Label
        FilterImageButton               matlab.ui.control.Button
        FilterselectDropDown            matlab.ui.control.DropDown
        FilterselectDropDownLabel       matlab.ui.control.Label
        SelectImageLabel                matlab.ui.control.Label
        FIlterselectionLabel            matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        LinearFiltersTab                matlab.ui.container.Tab
        TabGroup2_2                     matlab.ui.container.TabGroup
        MeanFilterTab_2                 matlab.ui.container.Tab
        LoadKernelButton                matlab.ui.control.Button
        AddNoiseButton_2                matlab.ui.control.Button
        ImpulseDensitySlider_2          matlab.ui.control.Slider
        ImpulseNoiseDensitySliderLabel_2  matlab.ui.control.Label
        GaussianSTDSlider_2             matlab.ui.control.Slider
        GaussianSTDSlider_2Label        matlab.ui.control.Label
        GaussianMeanSlider_2            matlab.ui.control.Slider
        GaussianMeanSlider_2Label       matlab.ui.control.Label
        MeanEditField_2                 matlab.ui.control.NumericEditField
        MeanEditField_2Label            matlab.ui.control.Label
        RMSEEditField_2                 matlab.ui.control.NumericEditField
        RMSEEditField_2Label            matlab.ui.control.Label
        STDEditField_2                  matlab.ui.control.NumericEditField
        STDEditField_2Label             matlab.ui.control.Label
        PSNREditField_2                 matlab.ui.control.NumericEditField
        PSNREditField_2Label            matlab.ui.control.Label
        FilterImageButton_2             matlab.ui.control.Button
        FilterselectDropDown_2          matlab.ui.control.DropDown
        FilterselectDropDown_2Label     matlab.ui.control.Label
        SelectImageLabel_2              matlab.ui.control.Label
        FIlterselectionLabel_2          matlab.ui.control.Label
        BrowseButton_2                  matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
    end

   properties (Access = public)
        image = NaN;
        meannoise_val;
        clean = NaN;
        image2 = NaN;        % For the second tab
        clean2 = NaN;        % Original clean image for the second tab
        impnoise_val;
        fselect = 0;
        gaussian_mean_val = 0; % Default value for Gaussian mean
        gaussian_std_val = 10; % Default value for Gaussian std deviation
        impulse_density_val = 0.05; % Default value for Impulse noise density
        gaussian_mean_val_2 = 0; % Gaussian mean for Tab 2
        gaussian_std_val_2 = 10; % Gaussian std deviation for Tab 2
        impulse_density_val_2 = 0.05; % Impulse noise density for Tab 2
        kernel = []; % To store the kernel loaded from a file
    end

    methods (Access = public)
        function psnr = calculatePSNR(app, alt, ref)
            if ~isequal(size(alt), size(ref))
                error('Input images must have the same dimensions');
            end
            max_pixel = double(max(alt, [], 'all'));
            num_pixel = size(ref, 1) * size(ref, 2) * size(ref, 3);
            mse = sum((double(ref) - double(alt)).^2, 'all') / num_pixel;
            psnr = -10 * log10(mse / max_pixel^2);
        end

        function rmse = calculateRMSE(app, alt, ref)
            if ~isequal(size(alt), size(ref))
                error('Input images must have the same dimensions');
            end
            num_pixel = size(ref, 1) * size(ref, 2) * size(ref, 3);
            mse = sum((double(ref) - double(alt)).^2, 'all') / num_pixel;
            rmse = sqrt(mse);
        end

        function meanVal = getImageMean(app, imageArray)
            imageArray = double(imageArray);
            meanVal = mean(imageArray(:));
        end

        function stdDev = getImageStdDev(app, imageArray)
            imageArray = double(imageArray);
            stdDev = std(imageArray(:));
        end

        % Add Gaussian Noise
        function noisy_img = addGaussianNoise(app, img)
            img = double(img);
            noise_mean = app.gaussian_mean_val;
            noise_std = app.gaussian_std_val;
            noise = noise_std * randn(size(img)) + noise_mean;
            noisy_img = max(0, min(255, img + noise));
            noisy_img = uint8(noisy_img);
        end

        % Add Gaussian Noise for Tab 2
        function noisy_img = addGaussianNoise_2(app, img)
            img = double(img);
            noise_mean = app.gaussian_mean_val_2;
            noise_std = app.gaussian_std_val_2;
            noise = noise_std * randn(size(img)) + noise_mean;
            noisy_img = max(0, min(255, img + noise));
            noisy_img = uint8(noisy_img);
        end

        % Add Impulse Noise
        function noisy_img = addImpulseNoise(app, img)
            density = app.impulse_density_val;
            [rows, cols, channels] = size(img);
            total_pixels = rows * cols;
            num_corrupted_pixels = round(density * total_pixels);
            noisy_img = img;
            pixel_indices = randperm(total_pixels, num_corrupted_pixels);
            num_salt = round(num_corrupted_pixels / 2);
            [r, c] = ind2sub([rows, cols], pixel_indices);
            for i = 1:num_salt
                noisy_img(r(i), c(i), :) = 255;
            end
            for i = num_salt+1:num_corrupted_pixels
                noisy_img(r(i), c(i), :) = 0;
            end
        end
        
        % Load Kernel from a Text File
        function kernel = loadKernel(app, filePath)
            % Load kernel from a text file
            try
                kernel = load(filePath); % Kernel should be a 3x3 matrix in the file
                if ~isequal(size(kernel), [3, 3])
                    error('Kernel must be a 3x3 matrix.');
                end
            catch
                error('Failed to load kernel. Ensure it is a 3x3 numeric matrix in a text file.');
            end
        end
    
        % Linear Convolution
        function filtered_img = linearConvolution(app, img, kernel)
            if isempty(kernel)
                error('Kernel is required for linear convolution.');
            end
            img = double(img);
            kernel = kernel / sum(kernel(:)); % Normalize kernel
            filtered_img = imfilter(img, kernel, 'symmetric', 'conv');
            filtered_img = uint8(filtered_img);
        end

        % Add Impulse Noise for Tab 2
        function noisy_img = addImpulseNoise_2(app, img)
            density = app.impulse_density_val_2;
            [rows, cols, channels] = size(img);
            total_pixels = rows * cols;
            num_corrupted_pixels = round(density * total_pixels);
            noisy_img = img;
            pixel_indices = randperm(total_pixels, num_corrupted_pixels);
            num_salt = round(num_corrupted_pixels / 2);
            [r, c] = ind2sub([rows, cols], pixel_indices);
            for i = 1:num_salt
                noisy_img(r(i), c(i), :) = 255;
            end
            for i = num_salt+1:num_corrupted_pixels
                noisy_img(r(i), c(i), :) = 0;
            end
        end

        % Mean Filter Implementation
        function filtered_img = meanFilter(app, img, window_size)
            if mod(window_size, 2) == 0
                error('Window size must be an odd integer');
            end
            img = double(img);
            [rows, cols, channels] = size(img);
            filtered_img = zeros(size(img));
            pad_size = floor(window_size / 2);
            for c = 1:channels
                padded_channel = padarray(img(:,:,c), [pad_size, pad_size], 'symmetric');
                for i = 1:rows
                    for j = 1:cols
                        neighborhood = padded_channel(i:i+window_size-1, j:j+window_size-1);
                        filtered_img(i, j, c) = mean(neighborhood(:));
                    end
                end
            end
            filtered_img = uint8(filtered_img);
        end
        
        % Smart Filter Implementation
        function filtered_img = smartFilter(app, img, kernel)
            % Validate kernel size
            if ~isequal(size(kernel), [3, 3])
                error('Kernel must be a 3x3 matrix.');
            end
            
            % Convert the image to double for processing
            img = double(img);
            
            % Calculate global variance
            global_var = var(img(:));
            
            % Prepare the output image
            filtered_img = zeros(size(img));
            
            % Pad the image for border handling
            padded_img = padarray(img, [1 1], 'symmetric');
            
            % Process each pixel
            [rows, cols] = size(img);
            for i = 1:rows
                for j = 1:cols
                    % Extract the 3x3 window
                    window = padded_img(i:i+2, j:j+2);
                    
                    % Local mean
                    local_mean = sum(window(:) .* kernel(:)) / sum(kernel(:));
                    
                    % Local variance
                    local_var = var(window(:));
                    
                    % Blending weight
                    weight = local_var / (local_var + global_var);
                    
                    % Apply the smart filter formula
                    filtered_img(i, j) = weight * img(i, j) + (1 - weight) * local_mean;
                end
            end
            
            % Convert back to uint8
            filtered_img = uint8(filtered_img);
        end


        % Median Filter Implementation
        function filtered_img = medianFilter(app, img, window_size)
            % Validate window size
            if mod(window_size, 2) == 0
                error('Window size must be an odd integer');
            end
        
            % Convert input image to double precision
            img = double(img);
        
            % Check if the input is grayscale or RGB
            [rows, cols, channels] = size(img);
            filtered_img = zeros(size(img));
        
            % Padding size
            padding = (window_size - 1) / 2;
        
            % Apply the median filter to each channel independently
            for c = 1:channels
                padded_channel = padarray(img(:,:,c), [padding padding], 'symmetric');
                for i = 1:rows
                    for j = 1:cols
                        window = padded_channel(i:i+window_size-1, j:j+window_size-1);
                        filtered_img(i, j, c) = median(window(:));
                    end
                end
            end
        
            % Convert back to uint8
            filtered_img = uint8(filtered_img);
        end

        % Rank-Order EV Filter
        function filtered_img = rankOrderEV(app, img)
            img = double(img);
            window_size = 3;
            pad_size = floor(window_size / 2);
            [rows, cols, channels] = size(img);
            filtered_img = zeros(size(img));
            for c = 1:channels
                padded_img = padarray(img(:,:,c), [pad_size, pad_size], 'symmetric');
                for i = 1:rows
                    for j = 1:cols
                        neighborhood = padded_img(i:i+window_size-1, j:j+window_size-1);
                        filtered_img(i, j, c) = mean(neighborhood(:));
                    end
                end
            end
            filtered_img = uint8(filtered_img);
        end

        % Impulse Noise Filtering with DRID and Median Filtering
        function filtered_img = dridFilter(app, img)
            [rows, cols, channels] = size(img);
            img = double(img);
            filtered_img = img;
            for c = 1:channels
                padded_img = padarray(img(:,:,c), [1 1], 'symmetric');
                for i = 2:rows+1
                    for j = 2:cols+1
                        window = padded_img(i-1:i+1, j-1:j+1);
                        center = padded_img(i, j);
                        differences = abs(window(:) - center);
                        if max(differences) > 50
                            filtered_img(i-1, j-1, c) = median(window(:));
                        end
                    end
                end
            end
            filtered_img = uint8(filtered_img);
        end

        % Rank Order ER Filter (Median) with a 3x3 Window
        function filtered_img = rankOrderERFilter(app, img)
            img = double(img);
            window_size = 3;
            pad_size = floor(window_size / 2);
            [rows, cols, channels] = size(img);
            filtered_img = img;
            for c = 1:channels
                padded_img = padarray(img(:,:,c), [pad_size pad_size], 'symmetric');
                for i = 1:rows
                    for j = 1:cols
                        window = padded_img(i:i+window_size-1, j:j+window_size-1);
                        filtered_img(i, j, c) = median(window(:));
                    end
                end
            end
            filtered_img = uint8(filtered_img);
        end

        % Unsharp Masking
        function filtered_img = unsharpMasking(app, img, alpha)
            img = double(img);
            blurred_img = imgaussfilt(img, 2);
            mask = img - blurred_img;
            filtered_img = img + alpha * mask;
            filtered_img = uint8(max(0, min(255, filtered_img)));
        end

        % Sobel Edge Detection
        function filtered_img = sobelEdgeDetection(app, img)
            img = double(rgb2gray(img));
            sobel_x = [-1 0 1; -2 0 2; -1 0 1];
            sobel_y = [-1 -2 -1; 0 0 0; 1 2 1];
            gx = imfilter(img, sobel_x, 'symmetric', 'conv');
            gy = imfilter(img, sobel_y, 'symmetric', 'conv');
            gradient_magnitude = sqrt(gx.^2 + gy.^2);
            filtered_img = uint8(max(0, min(255, gradient_magnitude)));
        end

        % Laplace I
        function filtered_img = laplaceIFilter(app, img)
            img = double(rgb2gray(img));
            laplace_kernel = [0 -1 0; -1 4 -1; 0 -1 0];
            filtered_img = imfilter(img, laplace_kernel, 'symmetric', 'conv');
            filtered_img = uint8(max(0, min(255, filtered_img)));
        end

        % Laplace II
        function filtered_img = laplaceIIFilter(app, img)
            img = double(rgb2gray(img));
            laplace_kernel = [-1 -1 -1; -1 8 -1; -1 -1 -1];
            filtered_img = imfilter(img, laplace_kernel, 'symmetric', 'conv');
            filtered_img = uint8(max(0, min(255, filtered_img)));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile({'*.tif;*.jpg;*.png;*.bmp;*.tiff', ...
                'Image Files (*.tif, *.jpg, *.png, *.bmp, *.tiff)'; ...
                '*.*', 'All Files (*.*)'}, 'Select an Image');
            if isequal(file, 0)
                return;
            end
            fullFilePath = fullfile(path, file);
            app.image = imread(fullFilePath);
            app.clean = imread(fullFilePath);
            cla(app.UIAxes, 'reset');
            imshow(app.image, 'Parent', app.UIAxes);
            axis(app.UIAxes, 'off');
        end

        % Callback function: AddNoiseButton, PSNREditField
        function AddNoiseButtonPushed(app, event)
            % Debugging statement
            disp("AddNoiseButtonPushed called");
        
            % Initialize the noisy image as the original image
            noisy_img = app.image;
        
            % Check and apply Gaussian noise if its values are non-default
            if app.gaussian_std_val > 0 || app.gaussian_mean_val ~= 0
                disp("Adding Gaussian Noise");
                noisy_img = app.addGaussianNoise(noisy_img); % Apply Gaussian noise
            else
                disp("Gaussian Noise not added (Mean = 0, Std Dev = 0)");
            end
        
            % Check and apply Impulse noise if its density is non-default
            if app.impulse_density_val > 0
                disp("Adding Impulse Noise");
                noisy_img = app.addImpulseNoise(noisy_img); % Apply Impulse noise
            else
                disp("Impulse Noise not added (Density = 0)");
            end
        
            % Display the noisy image in app.UIAxes_2
            cla(app.UIAxes_2, 'reset'); % Clear the existing axes
            imshow(noisy_img, 'Parent', app.UIAxes_2); % Show the noisy image
            axis(app.UIAxes_2, 'off'); % Turn off axes ticks
        
            % Update metrics for noisy image
            rmval = app.calculateRMSE(noisy_img, app.clean); % RMSE
            stval = app.getImageStdDev(noisy_img);          % Standard deviation
            pnval = app.calculatePSNR(noisy_img, app.clean); % PSNR
            mval = app.getImageMean(noisy_img);             % Mean value
        
            % Set metric values in the app
            app.RMSEEditField.Value = rmval; % Update RMSE field
            app.STDEditField.Value = stval; % Update STD field
            app.MeanEditField.Value = mval; % Update Mean field
            app.PSNREditField.Value = pnval; % Update PSNR field
        
            % Save the noisy image to app property
            app.image = noisy_img; % Store the noisy image for filtering
        end

        % Value changed function: FilterselectDropDown
        function FilterselectDropDownValueChanged(app, event)
            value = app.FilterselectDropDown.Value;
            switch value
                case 'Median Filter'
                    app.fselect = 0; % Median Filter
                case 'Smart Filter'
                    app.fselect = 1; % Smart Filter
                case 'Impulse Noise w/ DRID'
                    app.fselect = 2; % Impulse Noise w/ DRID
                case 'Rank Order ER(Median)'
                    app.fselect = 3; % Rank Order ER(Median)
            end
        end

        % Button pushed function: FilterImageButton
        function FilterImageButtonPushed(app, event)
            disp("FilterImageButtonPushed called");
        
            if isequal(app.image, app.clean)
                img_to_filter = app.clean; % Use loaded image
            else
                img_to_filter = app.image; % Use noisy image
            end
        
            % Apply selected filter
            switch app.fselect
                case 0 % Median Filter
                    filtered_img = app.medianFilter(img_to_filter, 3);
                case 1 % Smart Filter
                    kernel = [1, 2, 1; 2, 4, 2; 1, 2, 1];
                    kernel = kernel / sum(kernel(:));
                    filtered_img = app.smartFilter(img_to_filter, kernel);
                case 2 % Impulse Noise w/ DRID
                    filtered_img = app.dridFilter(img_to_filter); % Implement `dridFilter`
                case 3 % Rank Order ER(Median)
                    filtered_img = app.rankOrderERFilter(img_to_filter); % Implement `rankOrderERFilter`
                otherwise
                    disp("Invalid selection for Tab 1.");
                    return;
            end
        
            % Display and update metrics
            cla(app.UIAxes_2, 'reset');
            imshow(filtered_img, 'Parent', app.UIAxes_2);
            axis(app.UIAxes_2, 'off');
            app.RMSEEditField.Value = app.calculateRMSE(filtered_img, app.clean);
            app.STDEditField.Value = app.getImageStdDev(filtered_img);
            app.PSNREditField.Value = app.calculatePSNR(filtered_img, app.clean);
            app.MeanEditField.Value = app.getImageMean(filtered_img);
            app.image = filtered_img;
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [file, path] = uigetfile({'*.tif;*.jpg;*.png;*.bmp;*.tiff', ...
                'Image Files (*.tif, *.jpg, *.png, *.bmp, *.tiff)'; ...
                '*.*', 'All Files (*.*)'}, 'Select an Image');
            if isequal(file, 0)
                return;
            end
            fullFilePath = fullfile(path, file);
            app.image2 = imread(fullFilePath);
            app.clean2 = imread(fullFilePath);
            cla(app.UIAxes_3, 'reset');
            imshow(app.image2, 'Parent', app.UIAxes_3);
            axis(app.UIAxes_3, 'off');
        end

        % Button pushed function: FilterImageButton_2
        function FilterImageButton_2Pushed(app, event)
            % Select the image to filter
            img_to_filter = app.image2;

            % Apply selected filter
            switch app.fselect
                case 4 % Linear Convolution
                    if isempty(app.kernel)
                        disp('No kernel loaded. Using default 3x3 kernel.');
                        kernel = ones(3, 3) / 9; % Default averaging kernel
                    else
                        kernel = app.kernel;
                    end
                    filtered_img = app.linearConvolution(img_to_filter, kernel);
                case 5 % Mean Filter
                    filtered_img = app.meanFilter(img_to_filter, 3);
                case 6 % Rank-Order EV
                    filtered_img = app.rankOrderEV(img_to_filter);
                case 7 % Unsharp Masking
                    filtered_img = app.unsharpMasking(img_to_filter, 1.5);
                case 8 % Sobel
                    filtered_img = app.sobelEdgeDetection(img_to_filter);
                case 9 % Laplace I
                    filtered_img = app.laplaceIFilter(img_to_filter);
                case 10 % Laplace II
                    filtered_img = app.laplaceIIFilter(img_to_filter);
                otherwise
                    error('Invalid filter selection.');
            end
        
            % Display filtered image and update metrics
            cla(app.UIAxes_4, 'reset');
            imshow(filtered_img, 'Parent', app.UIAxes_4);
            app.RMSEEditField_2.Value = app.calculateRMSE(filtered_img, app.clean2);
            app.STDEditField_2.Value = app.getImageStdDev(filtered_img);
            app.PSNREditField_2.Value = app.calculatePSNR(filtered_img, app.clean2);
            app.MeanEditField_2.Value = app.getImageMean(filtered_img);
            app.image2 = filtered_img; % Save the filtered image
        end

        % Value changed function: GaussianMeanSlider
        function GaussianMeanSliderValueChanged(app, event)
            % Update Gaussian Mean value from slider
            app.gaussian_mean_val = event.Value;
        end

        % Callback function: GaussianSTDSlider, GaussianSTDSlider
        function GaussianSTDSliderValueChanged(app, event)
            % Update Gaussian Std Dev value from slider
            app.gaussian_std_val = event.Value;            
        end

        % Callback function: ImpulseDensitySlider, ImpulseDensitySlider
        function ImpulseDensitySliderValueChanged(app, event)
            % Update Impulse Noise Density from slider
            app.impulse_density_val = event.Value;             
        end

        % Value changing function: GaussianMeanSlider
        function GaussianMeanSliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Button pushed function: AddNoiseButton_2
        function AddNoiseButton_2Pushed(app, event)
            % Button pushed function: AddNoiseButton_2
            % Debugging statement
            disp("AddNoiseButton_2Pushed called");
        
            % Initialize the noisy image as the original clean image
            noisy_img = app.image2;
        
            % Check and apply Gaussian noise if its values are non-default
            if app.gaussian_std_val > 0 || app.gaussian_mean_val ~= 0
                disp("Adding Gaussian Noise");
                noisy_img = app.addGaussianNoise_2(noisy_img); % Apply Gaussian noise
            else
                disp("Gaussian Noise not added (Mean = 0, Std Dev = 0)");
            end
        
            % Check and apply Impulse noise if its density is non-default
            if app.impulse_density_val > 0
                disp("Adding Impulse Noise");
                noisy_img = app.addImpulseNoise_2(noisy_img); % Apply Impulse noise
            else
                disp("Impulse Noise not added (Density = 0)");
            end
        
            % Display the noisy image in app.UIAxes_4
            cla(app.UIAxes_4, 'reset'); % Clear the existing axes
            imshow(noisy_img, 'Parent', app.UIAxes_4); % Show the noisy image
            axis(app.UIAxes_4, 'off'); % Turn off axes ticks
            app.UIAxes_4.XColor = 'none'; % Hide X-axis
            app.UIAxes_4.YColor = 'none'; % Hide Y-axis
        
            % Update metrics for noisy image
            rmval = app.calculateRMSE(noisy_img, app.clean2); % RMSE
            stval = app.getImageStdDev(noisy_img);           % Standard deviation
            pnval = app.calculatePSNR(noisy_img, app.clean2); % PSNR
            mval = app.getImageMean(noisy_img);              % Mean value
        
            % Set metric values in the second tab fields
            app.RMSEEditField_2.Value = rmval; % Update RMSE field
            app.STDEditField_2.Value = stval; % Update STD field
            app.MeanEditField_2.Value = mval; % Update Mean field
            app.PSNREditField_2.Value = pnval; % Update PSNR field
        
            % Save the noisy image to app.image2 for further processing
            app.image2 = noisy_img; % Store the noisy image for filtering
        end

        % Callback function: GaussianMeanSlider_2, GaussianMeanSlider_2
        function GaussianMeanSlider_2ValueChanged(app, event)
            changingValue = event.Value;            
        end

        % Value changed function: GaussianSTDSlider_2
        function GaussianSTDSlider_2ValueChanged(app, event)
            changingValue = event.Value;
        end

        % Value changed function: ImpulseDensitySlider_2
        function ImpulseDensitySlider_2ValueChanged(app, event)
            changingValue = event.Value;           
        end

        % Value changed function: FilterselectDropDown_2
        function FilterselectDropDown_2ValueChanged(app, event)
        value = app.FilterselectDropDown_2.Value;
    switch value
        case 'Linear Convolution'
            app.fselect = 4; % Linear Convolution
        case 'Mean Filter'
            app.fselect = 5; % Mean Filter
        case 'Rank-Order EV'
            app.fselect = 6; % Rank-Order EV
        case 'Unsharp Masking'
            app.fselect = 7; % Unsharp Masking
        case 'Sobel'
            app.fselect = 8; % Sobel
        case 'Laplace I'
            app.fselect = 9; % Laplace I
        case 'Laplace II'
            app.fselect = 10; % Laplace II
        otherwise
            error('Invalid filter selection for Tab 2.');
    end  
        end

        % Button pushed function: LoadKernelButton
        function LoadKernelButtonPushed(app, event)
            [file, path] = uigetfile('*.txt', 'Select a Kernel File');
            if isequal(file, 0)
                disp('No kernel file selected.');
                return;
            end
            fullPath = fullfile(path, file);
            try
                kernel = load(fullPath);
                if ~isequal(size(kernel), [3, 3])
                    error('Kernel must be a 3x3 numeric matrix.');
                end
                app.kernel = kernel; % Store kernel in app property
                disp('Kernel successfully loaded:');
                disp(app.kernel);
            catch
                error('Failed to load kernel. Ensure it is a valid 3x3 numeric matrix.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.FilterChooseDropDownLabel = uilabel(app.HomeTab);
            app.FilterChooseDropDownLabel.Text = 'Filter Choose';
            app.FilterChooseDropDown = uidropdown(app.HomeTab);
            app.FilterChooseDropDown.Items = {'Home', 'Linear', 'Median', 'Impulse', 'Option 4'};
            app.FilterChooseDropDown.Value = 'Linear';
            app.NonLinearTab = uitab(app.TabGroup);
            app.NonLinearTab.Title = 'Non-Linear';
            app.TabGroup2 = uitabgroup(app.NonLinearTab);
            app.NonLinearTab_2 = uitab(app.TabGroup2);
            app.NonLinearTab_2.Title = 'Non-Linear';
            app.UIAxes = uiaxes(app.NonLinearTab_2);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes_2 = uiaxes(app.NonLinearTab_2);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.BrowseButton = uibutton(app.NonLinearTab_2, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FIlterselectionLabel = uilabel(app.NonLinearTab_2);
            app.FIlterselectionLabel.Text = 'FIlter selection';
            app.SelectImageLabel = uilabel(app.NonLinearTab_2);
            app.SelectImageLabel.Text = 'Select Image';
            app.FilterselectDropDownLabel = uilabel(app.NonLinearTab_2);
            app.FilterselectDropDownLabel.Text = 'Filter select';
            app.FilterselectDropDown = uidropdown(app.NonLinearTab_2);
            app.FilterselectDropDown.Items = {'Median Filter', 'Smart Filter', 'Impulse Noise w/ DRID', 'Rank Order ER (median)'};
            app.FilterselectDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterselectDropDownValueChanged, true);
            app.FilterselectDropDown.Placeholder = 'choose';
            app.FilterselectDropDown.Value = 'Median Filter';
            app.FilterImageButton = uibutton(app.NonLinearTab_2, 'push');
            app.FilterImageButton.ButtonPushedFcn = createCallbackFcn(app, @FilterImageButtonPushed, true);
            app.FilterImageButton.Text = 'Filter Image';
            app.PSNREditFieldLabel = uilabel(app.NonLinearTab_2);
            app.PSNREditFieldLabel.Text = 'PSNR';
            app.PSNREditField = uieditfield(app.NonLinearTab_2, 'numeric');
            app.PSNREditField.ValueChangedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.STDEditFieldLabel = uilabel(app.NonLinearTab_2);
            app.STDEditFieldLabel.Text = 'STD';
            app.STDEditField = uieditfield(app.NonLinearTab_2, 'numeric');
            app.RMSEEditFieldLabel = uilabel(app.NonLinearTab_2);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSEEditField = uieditfield(app.NonLinearTab_2, 'numeric');
            app.MeanEditFieldLabel = uilabel(app.NonLinearTab_2);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.NonLinearTab_2, 'numeric');
            app.GaussianMeanSliderLabel = uilabel(app.NonLinearTab_2);
            app.GaussianMeanSliderLabel.Text = 'Gaussian Mean';
            app.GaussianMeanSlider = uislider(app.NonLinearTab_2);
            app.GaussianMeanSlider.Limits = [-50 50];
            app.GaussianMeanSlider.MajorTicks = [-50 -30 -10 0 10 30 50];
            app.GaussianMeanSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianMeanSliderValueChanged, true);
            app.GaussianMeanSlider.ValueChangingFcn = createCallbackFcn(app, @GaussianMeanSliderValueChanging, true);
            app.GaussianSTDLabel = uilabel(app.NonLinearTab_2);
            app.GaussianSTDLabel.Text = 'Gaussian STD';
            app.GaussianSTDSlider = uislider(app.NonLinearTab_2);
            app.GaussianSTDSlider.Limits = [0 50];
            app.GaussianSTDSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianSTDSliderValueChanged, true);
            app.GaussianSTDSlider.ValueChangingFcn = createCallbackFcn(app, @GaussianSTDSliderValueChanged, true);
            app.ImpulseNoiseDensitySliderLabel = uilabel(app.NonLinearTab_2);
            app.ImpulseNoiseDensitySliderLabel.Text = 'Impulse Noise Density';
            app.ImpulseDensitySlider = uislider(app.NonLinearTab_2);
            app.ImpulseDensitySlider.Limits = [0 1];
            app.ImpulseDensitySlider.MajorTicks = [0 0.05 0.2 0.4 0.6 0.8 1];
            app.ImpulseDensitySlider.ValueChangedFcn = createCallbackFcn(app, @ImpulseDensitySliderValueChanged, true);
            app.ImpulseDensitySlider.ValueChangingFcn = createCallbackFcn(app, @ImpulseDensitySliderValueChanged, true);
            app.AddNoiseButton = uibutton(app.NonLinearTab_2, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = 'AddNoise';
            app.LinearFiltersTab = uitab(app.TabGroup);
            app.LinearFiltersTab.Title = 'Linear Filters';
            app.TabGroup2_2 = uitabgroup(app.LinearFiltersTab);
            app.MeanFilterTab_2 = uitab(app.TabGroup2_2);
            app.MeanFilterTab_2.Title = 'Mean Filter';
            app.UIAxes_3 = uiaxes(app.MeanFilterTab_2);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.UIAxes_4 = uiaxes(app.MeanFilterTab_2);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.ZTick = [];
            app.BrowseButton_2 = uibutton(app.MeanFilterTab_2, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.FIlterselectionLabel_2 = uilabel(app.MeanFilterTab_2);
            app.FIlterselectionLabel_2.Text = 'FIlter selection';
            app.SelectImageLabel_2 = uilabel(app.MeanFilterTab_2);
            app.SelectImageLabel_2.Text = 'Select Image';
            app.FilterselectDropDown_2Label = uilabel(app.MeanFilterTab_2);
            app.FilterselectDropDown_2Label.Text = 'Filter select';
            app.FilterselectDropDown_2 = uidropdown(app.MeanFilterTab_2);
            app.FilterselectDropDown_2.Items = {'Linear Convolution', 'Mean Filter', 'Rank-Order EV', 'Unsharp Masking', 'Sobel', 'Laplace I', 'Laplace II'};
            app.FilterselectDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FilterselectDropDown_2ValueChanged, true);
            app.FilterselectDropDown_2.Value = 'Linear Convolution';
            app.FilterImageButton_2 = uibutton(app.MeanFilterTab_2, 'push');
            app.FilterImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @FilterImageButton_2Pushed, true);
            app.FilterImageButton_2.Text = 'Filter Image';
            app.PSNREditField_2Label = uilabel(app.MeanFilterTab_2);
            app.PSNREditField_2Label.Text = 'PSNR';
            app.PSNREditField_2 = uieditfield(app.MeanFilterTab_2, 'numeric');
            app.STDEditField_2Label = uilabel(app.MeanFilterTab_2);
            app.STDEditField_2Label.Text = 'STD';
            app.STDEditField_2 = uieditfield(app.MeanFilterTab_2, 'numeric');
            app.RMSEEditField_2Label = uilabel(app.MeanFilterTab_2);
            app.RMSEEditField_2Label.Text = 'RMSE';
            app.RMSEEditField_2 = uieditfield(app.MeanFilterTab_2, 'numeric');
            app.MeanEditField_2Label = uilabel(app.MeanFilterTab_2);
            app.MeanEditField_2Label.Text = 'Mean';
            app.MeanEditField_2 = uieditfield(app.MeanFilterTab_2, 'numeric');
            app.GaussianMeanSlider_2Label = uilabel(app.MeanFilterTab_2);
            app.GaussianMeanSlider_2Label.Text = 'Gaussian Mean';
            app.GaussianMeanSlider_2 = uislider(app.MeanFilterTab_2);
            app.GaussianMeanSlider_2.Limits = [-50 50];
            app.GaussianMeanSlider_2.MajorTicks = [-50 -30 -10 0 10 30 50];
            app.GaussianMeanSlider_2.ValueChangedFcn = createCallbackFcn(app, @GaussianMeanSlider_2ValueChanged, true);
            app.GaussianMeanSlider_2.ValueChangingFcn = createCallbackFcn(app, @GaussianMeanSlider_2ValueChanged, true);
            app.GaussianSTDSlider_2Label = uilabel(app.MeanFilterTab_2);
            app.GaussianSTDSlider_2Label.Text = 'Gaussian STD';
            app.GaussianSTDSlider_2 = uislider(app.MeanFilterTab_2);
            app.GaussianSTDSlider_2.Limits = [0 50];
            app.GaussianSTDSlider_2.ValueChangedFcn = createCallbackFcn(app, @GaussianSTDSlider_2ValueChanged, true);
            app.ImpulseNoiseDensitySliderLabel_2 = uilabel(app.MeanFilterTab_2);
            app.ImpulseNoiseDensitySliderLabel_2.Text = 'Impulse Noise Density';
            app.ImpulseDensitySlider_2 = uislider(app.MeanFilterTab_2);
            app.ImpulseDensitySlider_2.Limits = [0 1];
            app.ImpulseDensitySlider_2.MajorTicks = [0 0.05 0.2 0.4 0.6 0.8 1];
            app.ImpulseDensitySlider_2.ValueChangedFcn = createCallbackFcn(app, @ImpulseDensitySlider_2ValueChanged, true);
            app.AddNoiseButton_2 = uibutton(app.MeanFilterTab_2, 'push');
            app.AddNoiseButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButton_2Pushed, true);
            app.AddNoiseButton_2.Text = 'AddNoise';
            app.LoadKernelButton = uibutton(app.MeanFilterTab_2, 'push');
            app.LoadKernelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadKernelButtonPushed, true);
            app.LoadKernelButton.Text = 'Load Kernel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appv5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end