classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        CreatedbyLabel                 matlab.ui.control.Label
        ImageFilteringSoftwareLabel    matlab.ui.control.Label
        LinearFiltersTab               matlab.ui.container.Tab
        SelecttheChannelDropDown       matlab.ui.control.DropDown
        SelecttheChannelDropDownLabel  matlab.ui.control.Label
        PSNRRMSETextArea               matlab.ui.control.TextArea
        ApplyFilterButton              matlab.ui.control.Button
        ImpulseNoiseSlider             matlab.ui.control.Slider
        ImpulseNoiseSliderLabel        matlab.ui.control.Label
        GaussianNoiseSlider            matlab.ui.control.Slider
        GaussianNoiseSliderLabel       matlab.ui.control.Label
        SelectFilterDropDown           matlab.ui.control.DropDown
        SelectFilterDropDownLabel      matlab.ui.control.Label
        ResultingImageandPSNRRMSEDataLabel  matlab.ui.control.Label
        LinearChannelFiltersLabel      matlab.ui.control.Label
        LoadImageButton                matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        NonLinearFiltersTab            matlab.ui.container.Tab
        SelecttheChannelDropDown_2     matlab.ui.control.DropDown
        SelecttheChannelDropDown_2Label  matlab.ui.control.Label
        PSNRRMSETextArea_2             matlab.ui.control.TextArea
        ApplyFilterButton_2            matlab.ui.control.Button
        ImpulseNoiseSlider_2           matlab.ui.control.Slider
        ImpulseNoiseSlider_2Label      matlab.ui.control.Label
        GaussianNoiseSlider_2          matlab.ui.control.Slider
        GaussianNoiseSlider_2Label     matlab.ui.control.Label
        SelectFilterDropDown_2         matlab.ui.control.DropDown
        SelectFilterDropDown_2Label    matlab.ui.control.Label
        ResultingImageandPSNRRMSEDataLabel_2  matlab.ui.control.Label
        LinearChannelFiltersLabel_2    matlab.ui.control.Label
        LoadImageButton_2              matlab.ui.control.Button
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    Image % To store the loaded image
        Property2 % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton, LoadImageButton_2
        function LoadImageButtonPushed(app, event)
                     % Open file selection dialog
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Image Files (*.jpg, *.png, *.bmp, *.tif)'}, ...
                                     'Select an Image File');
            if isequal(file, 0)
                % User canceled file selection
                return;
            end
        
            % Read the selected image
            fullPath = fullfile(path, file);
            app.Image = imread(fullPath);
        
            % Standardize the image size
            standardSize = [500, 500]; % Set standard size for all images
            app.Image = imresize(app.Image, standardSize);
        
            % Clear any existing content in the axes
            cla(app.UIAxes, 'reset'); % Reset the axes before displaying the new image
        
            % Display the image in the axes
            imshow(app.Image, 'Parent', app.UIAxes);
            axis(app.UIAxes, 'off'); % Remove axis labels and ticks
            app.UIAxes.XColor = 'none'; % Hide x-axis
            app.UIAxes.YColor = 'none'; % Hide y-axis
        
            % Debugging: Check the size of the image after resizing
            disp(size(app.Image)); % Should display [500, 500, 3] for an RGB image
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.ImageFilteringSoftwareLabel = uilabel(app.HomeTab);
            app.ImageFilteringSoftwareLabel.Text = 'Image Filtering Software';
            app.CreatedbyLabel = uilabel(app.HomeTab);
            app.CreatedbyLabel.Text = 'Created by ...';
            app.LinearFiltersTab = uitab(app.TabGroup);
            app.LinearFiltersTab.Title = 'Linear Filters';
            app.UIAxes = uiaxes(app.LinearFiltersTab);
            title(app.UIAxes, 'Please load an image to filter')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.LinearFiltersTab);
            title(app.UIAxes_2, 'Filtered Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.LoadImageButton = uibutton(app.LinearFiltersTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.LinearChannelFiltersLabel = uilabel(app.LinearFiltersTab);
            app.LinearChannelFiltersLabel.Text = 'Linear Channel Filters';
            app.ResultingImageandPSNRRMSEDataLabel = uilabel(app.LinearFiltersTab);
            app.ResultingImageandPSNRRMSEDataLabel.Text = 'Resulting Image and PSNR/RMSE Data';
            app.SelectFilterDropDownLabel = uilabel(app.LinearFiltersTab);
            app.SelectFilterDropDownLabel.Text = 'Select Filter';
            app.SelectFilterDropDown = uidropdown(app.LinearFiltersTab);
            app.SelectFilterDropDown.Items = {'...', 'Median Filter w/ 3x3 window', 'Smart Filter with a 3x3 window', 'Impulse noise filtering (DRID + Median)', 'Rank-order EV Filter (mean)', 'Rank-Order EV Filter (median)'};
            app.SelectFilterDropDown.Value = '...';
            app.GaussianNoiseSliderLabel = uilabel(app.LinearFiltersTab);
            app.GaussianNoiseSliderLabel.Text = 'Gaussian Noise';
            app.GaussianNoiseSlider = uislider(app.LinearFiltersTab);
            app.ImpulseNoiseSliderLabel = uilabel(app.LinearFiltersTab);
            app.ImpulseNoiseSliderLabel.Text = 'Impulse Noise';
            app.ImpulseNoiseSlider = uislider(app.LinearFiltersTab);
            app.ApplyFilterButton = uibutton(app.LinearFiltersTab, 'push');
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.PSNRRMSETextArea = uitextarea(app.LinearFiltersTab);
            app.SelecttheChannelDropDownLabel = uilabel(app.LinearFiltersTab);
            app.SelecttheChannelDropDownLabel.Text = 'Select the Channel';
            app.SelecttheChannelDropDown = uidropdown(app.LinearFiltersTab);
            app.SelecttheChannelDropDown.Items = {'...', 'RGB', 'Luminosity-Y'};
            app.SelecttheChannelDropDown.Value = '...';
            app.NonLinearFiltersTab = uitab(app.TabGroup);
            app.NonLinearFiltersTab.Title = 'Non-Linear Filters';
            app.UIAxes_3 = uiaxes(app.NonLinearFiltersTab);
            title(app.UIAxes_3, 'Please load an image to filter')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.NonLinearFiltersTab);
            title(app.UIAxes_4, 'Filtered Image')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.LoadImageButton_2 = uibutton(app.NonLinearFiltersTab, 'push');
            app.LoadImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton_2.Text = 'Load Image';
            app.LinearChannelFiltersLabel_2 = uilabel(app.NonLinearFiltersTab);
            app.LinearChannelFiltersLabel_2.Text = 'Linear Channel Filters';
            app.ResultingImageandPSNRRMSEDataLabel_2 = uilabel(app.NonLinearFiltersTab);
            app.ResultingImageandPSNRRMSEDataLabel_2.Text = 'Resulting Image and PSNR/RMSE Data';
            app.SelectFilterDropDown_2Label = uilabel(app.NonLinearFiltersTab);
            app.SelectFilterDropDown_2Label.Text = 'Select Filter';
            app.SelectFilterDropDown_2 = uidropdown(app.NonLinearFiltersTab);
            app.SelectFilterDropDown_2.Items = {'...', 'Linear Convolution w/ 3x3 Window', 'Mean Filter w/ 3x3 Window', 'Unsharp Masking w/ a 3x3 window', 'Sobel Edge detector', 'Laplace I', 'Laplace II'};
            app.SelectFilterDropDown_2.Value = '...';
            app.GaussianNoiseSlider_2Label = uilabel(app.NonLinearFiltersTab);
            app.GaussianNoiseSlider_2Label.Text = 'Gaussian Noise';
            app.GaussianNoiseSlider_2 = uislider(app.NonLinearFiltersTab);
            app.ImpulseNoiseSlider_2Label = uilabel(app.NonLinearFiltersTab);
            app.ImpulseNoiseSlider_2Label.Text = 'Impulse Noise';
            app.ImpulseNoiseSlider_2 = uislider(app.NonLinearFiltersTab);
            app.ApplyFilterButton_2 = uibutton(app.NonLinearFiltersTab, 'push');
            app.ApplyFilterButton_2.Text = 'Apply Filter';
            app.PSNRRMSETextArea_2 = uitextarea(app.NonLinearFiltersTab);
            app.SelecttheChannelDropDown_2Label = uilabel(app.NonLinearFiltersTab);
            app.SelecttheChannelDropDown_2Label.Text = 'Select the Channel';
            app.SelecttheChannelDropDown_2 = uidropdown(app.NonLinearFiltersTab);
            app.SelecttheChannelDropDown_2.Items = {'...', 'RGB', 'Luminosity-Y'};
            app.SelecttheChannelDropDown_2.Value = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end